classdef GUIepochPlayer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        playButton                  matlab.ui.control.Button
        previousButton              matlab.ui.control.Button
        EpochLabelDropDownLabel     matlab.ui.control.Label
        EpochLabelDropDown          matlab.ui.control.DropDown
        loadpatientfileButton       matlab.ui.control.Button
        nextButton                  matlab.ui.control.Button
        saveButton                  matlab.ui.control.Button
        stopButton                  matlab.ui.control.Button
        SelectedEpochTextAreaLabel  matlab.ui.control.Label
        SelectedEpochTextArea       matlab.ui.control.TextArea
        waveformAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        analysisTableSummary
        analysisTablePauseDetails
        analysisTableSpeechDetails
        energyMatrix
        filename
        audioName;
        indDetect;
        waveformWithTime;
        detectionWTime
        indSpeechStart;
        indSpeechStop;
        Fs;
        player;
        validate;
        
        
        EpochLabel;
        audioFile;
        waveform;
        matFilename;
        audioWExt;
        patientDx;
        pExist;
        saveCheck;
        epochMsg;
        stopVal;
        pathname;
        epochDone;
        
        epochTotal;
        
        epochGroups;
        xlim1;
        xlim2;
        i;
        h;
        Text;
        Ti;
        labelString;
        
    end
    
    properties (Access = public)
        % Description
    end
    
    methods (Access = private)
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Speech Epoch Audio Player and Labeler';
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0.1;
            bottom = screenHeight*0.1;
            width = screenWidth*0.8;
            height = screenHeight*0.8;
            drawnow;
            app.UIFigure.Position = [left bottom width height];
            app.previousButton.Enable = 'off';
            app.nextButton.Enable = 'off';
            app.playButton.Enable = 'off';
            app.saveButton.Enable = 'off';
            app.stopButton.Enable = 'off';
            app.saveCheck = 0;
            app.SelectedEpochTextArea.Editable = 'off';
            fig1 = uifigure;
            clear mode
            message1 = sprintf('Choose Editing Mode');
            mode = uiconfirm(fig1,message1, 'Editing Mode',...
                'Options', {'First Runthrough', 'Validation', 'Cancel'}, 'DefaultOption',1, 'CancelOption', 3);
            mode = convertCharsToStrings(mode)
            if mode == "Validation"
                app.validate = 1;
            else
                app.validate = 0;
            end
            delete(fig1);
            
            
        end

        % Button pushed function: playButton
        function playButtonPushed(app, event)
            if (app.i <= length(app.indSpeechStart))
                if(app.i == 1)
                    app.previousButton.Enable = 'off'
                else
                    app.previousButton.Enable = 'on'
                end
                clear app.epochDone
                app.epochDone = num2str(app.i);
                app.epochTotal = num2str(length(app.indSpeechStart));
                app.epochMsg = [app.epochDone ' of ' app.epochTotal];
                app.SelectedEpochTextArea.Value = app.epochMsg;
                
                
                app.EpochLabelDropDown.Value = app.labelString{app.i};
                
                time = 0;
                clear sound
                sound(app.waveformWithTime(1,app.indSpeechStart(app.i):app.indSpeechStop(app.i)), app.Fs)
                tic % Starts Matlab timer
                t=toc;
                while 1
                    if ((time > app.epochGroups(app.i,2)) || (app.stopVal == 1))
                        app.stopVal = 0;
                        break
                    end
                    time = app.epochGroups(app.i,1) + t;
                    set(app.h,'xdata',(time)*[1,1])
                    drawnow
                    t= toc; % get the current time for the next update
                    if(app.h.XData(1) > (app.xlim1 + 18))
                        app.xlim1 = app.xlim1 + 15;
                        app.xlim2 = app.xlim2 + 15;
                        xlim(app.waveformAxes, [app.xlim1 app.xlim2]);
                    elseif(app.h.XData(1) < (app.xlim1 + 2) )
                        app.xlim1 = app.xlim1 - 10;
                        app.xlim2 = app.xlim2 - 10;
                        xlim(app.waveformAxes, [app.xlim1 app.xlim2])
                    end

                end
                if(app.validate == 1 && app.i < length(app.indSpeechStart))
                    app.nextButtonPushed
                end
                
            end
            
        end

        % Button pushed function: previousButton
        function previousButtonPushed(app, event)
            if(app.i - 1 == 1)
                app.previousButton.Enable = 'off'
            else
                app.previousButton.Enable = 'on'
            end
            app.i = app.i - 1;
            app.EpochLabelDropDown.Value = app.labelString{app.i};
            set(app.h,'xdata',(app.epochGroups(app.i,1))*[1,1]);
            if(app.h.XData(1) > (app.xlim1 + 18))
                app.xlim1 = app.xlim1 + 15;
                app.xlim2 = app.xlim2 + 15;
                xlim(app.waveformAxes, [app.xlim1 app.xlim2]);
            elseif(app.h.XData(1) < (app.xlim1 + 2) )
                app.xlim1 = app.xlim1 - 10;
                app.xlim2 = app.xlim2 - 10;
                xlim(app.waveformAxes, [app.xlim1 app.xlim2])
            end
            
        end

        % Button pushed function: loadpatientfileButton
        function loadpatientfileButtonPushed(app, event)
            cla(app.waveformAxes,'reset')
            clear app.analysisTableSummary app.analysisTablePauseDetails app.analysisTableSpeechDetail app.energyMatrix app.filename app.audioName...
                app.indDetect app.waveformWithTime app.detectionWTime app.indSpeechStart app.indSpeechStop app.Fs app.EpochLabel app.audioFile app.waveform...
                app.matFilename app.audioWExt app.patientDx app.pExist app.epochGroups app.xlim1 app.xlim2 app.i app.h app.Text app.Ti app.labelString...
                app.pathname app.epochDone app.epochTotal app.epochMsg analysisTableSummary analysisTablePauseDetails analysisTableSpeechDetail energyMatrix filname...
                audioName indDetect waveformWithTime detectionWTime indSpeechStart indSpeechStop Fs EpochLabel audioFile waveform audioWExt;
            
                
            [filename, app.pathname, ~] = uigetfile('*.mat', 'Load Patient Mat file');
            app.matFilename = strcat(app.pathname,filename);
            
            load(app.matFilename)
            
            app.SelectedEpochTextArea.Value = '';
            %app.playButton.Enable = 'on'
            app.analysisTableSummary = analysisTableSummary;
            app.analysisTablePauseDetails = analysisTablePauseDetails;
            app.analysisTableSpeechDetails = analysisTableSpeechDetails;
            app.energyMatrix = energyMatrix;
            app.filename = filename;
            app.audioName = audioName;
            app.indSpeechStart = indSpeechStart;
            app.indSpeechStop = indSpeechStop;
            %app.audioWExt = audioWExt;
            app.audioWExt = [app.audioName '.m4a']
            app.audioFile = strcat(app.pathname,app.audioWExt);
            
            if(exist('patientDx','var') == 1)
                app.pExist = 1;
                app.patientDx = patientDx;
            else
                app.pExist = 0;
            end
            
            
            [app.waveform,app.Fs] = audioread(app.audioFile);
            
            
            if(length(app.waveform(1,:)) ~= 1) % Condition is true when file is a recording in stereo.
                app.waveform = sum(app.waveform, 2) / size(app.waveform,2); % Converts stereo recording to mono.
            end
            
            dt = 1/app.Fs;
            t = 0:dt:length(app.waveform)*dt - dt; %array of each sample in time domain
            app.waveformWithTime = zeros(2,(length(app.waveform)+2));
            app.waveformWithTime(1,(2:end - 1)) = app.waveform;
            app.waveformWithTime(2,(2:end - 1)) = t;
            app.waveformWithTime(2,end) = t(end);
            
            app.detectionWTime = zeros(1,length(app.waveformWithTime));
            
            for w=1:length(app.indSpeechStart)
                app.detectionWTime(app.indSpeechStart(w):app.indSpeechStop(w)) = 1;
            end
            
            app.i = 1;
            plot(app.waveformAxes, app.waveformWithTime(2,:), app.waveformWithTime(1,:))
            hold(app.waveformAxes,'on')
            plot(app.waveformAxes, app.waveformWithTime(2,:),app.detectionWTime, 'color', [0.4 0.2 0.6])
            app.waveformAxes.YLim = [-1.2,1.2];
            app.xlim1 = 0;
            app.xlim2 = 20;
            app.waveformAxes.XLim = [app.xlim1 app.xlim2];
            app.h=line(app.waveformAxes, [0,0], [-60,5],'color','k','marker', 'o', 'linewidth', 2);
            app.waveformAxes.Title.String = num2str(app.audioName);
            app.waveformAxes.XLabel.String = 'Time (s)';
            app.waveformAxes.YLabel.String = 'Waveform Magnitude';
            app.Text = NaN(length(app.indSpeechStart),4);
            app.Ti = 1;
            app.saveButton.Enable = 'on';
            app.playButton.Enable = 'on';
            app.nextButton.Enable = 'on';
            app.stopButton.Enable = 'on';
            app.labelString = cell(length(app.indSpeechStart),1);
            %app.labelString{:,1} = '';
            app.stopVal = 0;
            app.EpochLabel = cell(1,length(app.indSpeechStart));
            
            for x = 1:length(app.indSpeechStart)
                app.labelString{x,1} = '';
            end
            %legend(app.waveformAxes, 'Waveform', 'Detection')
            
            for z = 1:length(app.indSpeechStart)
                epoch = app.waveformWithTime(2,app.indSpeechStart(z):app.indSpeechStop(z));
                app.epochGroups(z,1) = epoch(1);
                app.epochGroups(z,2) = epoch(end);
            end
            
            %{
            fig1 = uifigure;
            clear mode
            message1 = sprintf('Choose Editing Mode');
            mode = uiconfirm(fig1,message1, 'Editing Mode',...
                'Options', {'First Runthrough', 'Validation', 'Cancel'}, 'DefaultOption',1, 'CancelOption', 3);
            mode = convertCharsToStrings(mode);
            if mode == "Validation"
                app.secondRun = 1;
            end
            delete(fig1);
            %}
            
            if(exist('EpochLabel', 'var') == 1)
                app.EpochLabel = EpochLabel;
                
                
                for x = 1:length(app.EpochLabel)
                    value = app.EpochLabel{x};
                    
                    %{
                    if(isempty(value) == 1)
                        app.nextButton.Enable = 'off';
                    else
                        app.nextButton.Enable = 'on';
                    end
                    %}
                    
                    clear mark
                    
                    if(isnan(app.Text(app.i,app.Ti)) == 0)
                        delete(app.Text(app.i,app.Ti));
                        app.Text(app.i,:) = NaN(1,4);
                    end
                    
                    switch value
                        case 'P'
                            app.labelString{x} = 'Patient';
                            %app.EpochLabel{x} = 'P';
                            mark = 'P';
                            app.Ti = 1;
                            %if(exist('app.epochGroups(x,1)', 'var') == 1)
                                xpos = app.epochGroups(x,1);
                                app.Text(x,1) = text(app.waveformAxes,xpos, 1.05, mark, 'FontSize', 14, 'FontWeight', 'bold');
                            %end
                        case 'C'
                            app.labelString{x} = 'Clinician';
                            %app.EpochLabel{x} = 'C';
                            mark = 'C';
                            app.Ti = 2;
                            %if(exist('app.epochGroups(x,1)', 'var') == 1)
                                xpos = app.epochGroups(x,1);
                                app.Text(x,2) = text(app.waveformAxes,xpos, 1.05, mark, 'FontSize', 14, 'FontWeight', 'bold');
                            %end
                        case 'B'
                            app.labelString{x} = 'Buzzer';
                            %app.EpochLabel{x} = 'B';
                            mark = 'B';
                            app.Ti = 3;
                            %if(exist('app.epochGroups(x,1)', 'var') == 1)
                                xpos = app.epochGroups(x,1);
                                app.Text(x,3) = text(app.waveformAxes,xpos, 1.05, mark, 'FontSize', 14, 'FontWeight', 'bold');
                            %end
                        case 'O'
                            app.labelString{x} = 'Other';
                           %app.EpochLabel{x} = 'O';
                            mark = 'O';
                            app.Ti = 4;
                            %if(exist('app.epochGroups(x,1)', 'var') == 1)
                                xpos = app.epochGroups(x,1);
                                app.Text(x,4) = text(app.waveformAxes,xpos, 1.05, mark, 'FontSize', 14, 'FontWeight', 'bold');
                            %end
                    end
                end
                if(app.validate == 1)
                    app.i = 1;
                else
                    app.i = x;
                end
            end
            
            
        end

        % Value changed function: EpochLabelDropDown
        function EpochLabelDropDownValueChanged(app, event)
            if(app.i ~= (length(app.indSpeechStart) + 1) || app.validate == 1)
                
                value = app.EpochLabelDropDown.Value;
                
                
                clear mark
                
                if(isnan(app.Text(app.i,app.Ti)) == 0)
                    delete(app.Text(app.i,app.Ti));
                    app.Text(app.i,:) = NaN(1,4);
                end
                
                switch value
                    case 'Patient'
                        app.labelString{app.i} = 'Patient';
                        if app.validate == 1
                            clear app.EpochLabel{app.i}
                        end
                        app.EpochLabel{1,app.i} = 'P';
                        mark = 'P';
                        app.Ti = 1;
                        xpos = app.epochGroups(app.i,1);
                        app.Text(app.i,1) = text(app.waveformAxes,xpos, 1.05, mark, 'FontSize', 14, 'FontWeight', 'bold');
                        if app.validate == 0
                            app.nextButtonPushed
                            pause(.5)
                            app.playButtonPushed
                        end
                    case 'Clinician'
                        app.labelString{app.i} = 'Clinician';
                        if app.validate == 1
                            clear app.EpochLabel{app.i}
                        end
                        app.EpochLabel{1,app.i} = 'C';
                        mark = 'C';
                        app.Ti = 2;
                        xpos = app.epochGroups(app.i,1);
                        app.Text(app.i,2) = text(app.waveformAxes,xpos, 1.05, mark, 'FontSize', 14, 'FontWeight', 'bold');
                        if app.validate == 0
                            app.nextButtonPushed
                            pause(.5)
                            app.playButtonPushed
                        end
                    case 'Buzzer'
                        app.labelString{app.i} = 'Buzzer';
                        if app.validate == 1
                            clear app.EpochLabel{app.i}
                        end
                        app.EpochLabel{1,app.i} = 'B';
                        mark = 'B';
                        app.Ti = 3;
                        xpos = app.epochGroups(app.i,1);
                        app.Text(app.i,3) = text(app.waveformAxes,xpos, 1.05, mark, 'FontSize', 14, 'FontWeight', 'bold');
                        if app.validate == 0
                            app.nextButtonPushed
                            pause(.5)
                            app.playButtonPushed
                        end
                    case 'Other'
                        app.labelString{app.i} = 'Other';
                        if app.validate == 1
                            clear app.EpochLabel{app.i}
                        end
                        app.EpochLabel{1,app.i} = 'O';
                        mark = 'O';
                        app.Ti = 4;
                        xpos = app.epochGroups(app.i,1);
                        app.Text(app.i,4) = text(app.waveformAxes,xpos, 1.05, mark, 'FontSize', 14, 'FontWeight', 'bold');
                        if app.validate == 0
                            app.nextButtonPushed
                            pause(.5)
                            app.playButtonPushed
                        end
                end
            end


        end

        % Button pushed function: nextButton
        function nextButtonPushed(app, event)
            if((app.i + 1) == 1)
                app.previousButton.Enable = 'off'
            else
                app.previousButton.Enable = 'on'
            end
            
            if(app.i + 1<= length(app.indSpeechStart))
                app.i = app.i + 1;
                app.EpochLabelDropDown.Value = app.labelString{app.i};
                
                set(app.h,'xdata',(app.epochGroups(app.i,1))*[1,1]);
                if(app.h.XData(1) > (app.xlim1 + 18))
                    app.xlim1 = app.xlim1 + 15;
                    app.xlim2 = app.xlim2 + 15;
                    xlim(app.waveformAxes, [app.xlim1 app.xlim2]);
                elseif(app.h.XData(1) < (app.xlim1 + 2) )
                    app.xlim1 = app.xlim1 - 2;
                    app.xlim2 = app.xlim2 - 2;
                    xlim(app.waveformAxes, [app.xlim1 app.xlim2])
                end
            end
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            app.stopButtonPushed;
            analysisTableSummary = app.analysisTableSummary;
            analysisTablePauseDetails = app.analysisTablePauseDetails;
            analysisTableSpeechDetails = app.analysisTableSpeechDetails;
            energyMatrix = app.energyMatrix;
            [~, filename] = fileparts(app.matFilename);
            audioName = app.audioName;
            indDetect = app.indDetect;
            waveformWithTime = app.waveformWithTime;
            detectionWTime = app.detectionWTime;
            indSpeechStart = app.indSpeechStart;
            indSpeechStop = app.indSpeechStop;
            Fs = app.Fs;
            audioWExt = app.audioWExt;
            if(app.pExist == 1)
                patientDx = app.patientDx;
            end
            EpochLabel = app.EpochLabel;
            if(filename(1:8) == 'LABELED_')
                newFileName = filename;
            else
                newFileName = ['LABELED_',filename];
            end
            app.UIFigure.Interruptible = 'off'
            if(app.pExist == 1)
                cd(app.pathname )
                uisave({'analysisTableSummary', 'analysisTablePauseDetails', 'analysisTableSpeechDetails',...
                    'energyMatrix', 'filename', 'audioName',  'indSpeechStart', 'indSpeechStop', 'Fs', 'audioWExt',...
                    'patientDx', 'EpochLabel'},newFileName)
                app.saveCheck = 1;
            else
                cd(app.pathname)
                uisave({'analysisTableSummary', 'analysisTablePauseDetails', 'analysisTableSpeechDetails',...
                    'energyMatrix', 'filename', 'audioName',  'indSpeechStart', 'indSpeechStop', 'Fs','audioWExt',...
                    'EpochLabel'},newFileName)
                app.saveCheck = 1;
            end
            app.UIFigure.Interruptible = 'on'
            %directory = uigetdir(currentFolder,'Pick a folder to save ');
            %fullDirectory = [directory,'\', app.audioName];
            %save(fullDirectory, 'epochLabels', '-append')
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            clear sound
            app.stopVal = 1;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.stopButtonPushed;
            if((app.saveCheck == 0) && (isempty(app.EpochLabel) == 0))
                fig = uifigure;
                clear selection
                message = sprintf('You have unsaved work, \n Save Now?');
                selection = uiconfirm(fig,message, 'Unsaved Work',...
                    'Options', {'Save', 'Cancel'}, 'DefaultOption',1, 'CancelOption', 2);
                selection = convertCharsToStrings(selection);
                if(selection == "Save")
                    app.saveButtonPushed;
                    delete(fig)
                else
                    delete(fig)
                    return;
                end
                delete(fig)
            end
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.playButton = uibutton(app.UIFigure, 'push');
            app.playButton.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.playButton.Text = 'play';
            app.previousButton = uibutton(app.UIFigure, 'push');
            app.previousButton.ButtonPushedFcn = createCallbackFcn(app, @previousButtonPushed, true);
            app.previousButton.Text = 'previous';
            app.EpochLabelDropDownLabel = uilabel(app.UIFigure);
            app.EpochLabelDropDownLabel.Text = 'Epoch Label';
            app.EpochLabelDropDown = uidropdown(app.UIFigure);
            app.EpochLabelDropDown.Items = {'', 'Patient', 'Clinician', 'Buzzer', 'Other'};
            app.EpochLabelDropDown.ValueChangedFcn = createCallbackFcn(app, @EpochLabelDropDownValueChanged, true);
            app.EpochLabelDropDown.Value = '';
            app.loadpatientfileButton = uibutton(app.UIFigure, 'push');
            app.loadpatientfileButton.ButtonPushedFcn = createCallbackFcn(app, @loadpatientfileButtonPushed, true);
            app.loadpatientfileButton.Text = 'load patient file';
            app.nextButton = uibutton(app.UIFigure, 'push');
            app.nextButton.ButtonPushedFcn = createCallbackFcn(app, @nextButtonPushed, true);
            app.nextButton.Text = 'next';
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.stopButton = uibutton(app.UIFigure, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = 'stop';
            app.SelectedEpochTextAreaLabel = uilabel(app.UIFigure);
            app.SelectedEpochTextAreaLabel.Text = 'Selected Epoch';
            app.SelectedEpochTextArea = uitextarea(app.UIFigure);
            app.SelectedEpochTextArea.Editable = 'off';
            app.waveformAxes = uiaxes(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUIepochPlayer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end