classdef GUI_play_epoch_label_filters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        PlayRawButton              matlab.ui.control.Button
        PlayFilteredButton         matlab.ui.control.Button
        StopButton                 matlab.ui.control.Button
        waveformAxes               matlab.ui.control.UIAxes
        LoadButton                 matlab.ui.control.Button
        SelectFilterDropDownLabel  matlab.ui.control.Label
        SelectFilterDropDown       matlab.ui.control.DropDown
    end

    %% GUI_play_epoch_label_filters.mlapp is a GUI that reads in 
    % a patient mat file with respective speech epoch labels and 
    % audio file and allows the user to listen to all the different 
    % filtered speech detection dependent on the epoch labels. 
    % This inlcudes a raw speech detection comparison and plot
    % visualization all different detected speech 
    
    %% CURRENT BUGS: Within a session, if the user loads in a 
    % patient mat file after listening to filtered audio of previous
    % patient mat file if the 'Play filtered audio' button is pressed
    % without changing the current dropdown selection, an error will occur.
    % Simply change the dropdown selection and play it. The previously 
    % selected filter dropdown will now work.
    
    properties (Access = private)
        analysisTableSummary;
        analysisTablePauseDetails;
        analysisTableSpeechDetails;
        energyMatrix;
        filename;
        audioName;
        indDetect;
        waveformWithTime;
        detectionWTimeRaw;
        indSpeechStart;
        indSpeechStop;
        Fs;
        audioWExt;
        audioFile;
        patientDx;
        EpochLabel
        waveform;
        
        pathname;
        matFilename;
        h;
        
        detectionWTimePFilt;
        detectionWTimeCFilt;
        detectionWTimeBFilt;
        detectionWTimeOFilt;
        
        indRawDetection;
        indPatientAudioOnly;
        indClinicianAudioOnly;
        indBuzzerAudioOnly;
        indOtherAudioOnly;
        
        Title;
        filtDectection;
        filtIndices;


    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% initialize GUI specific parameters
            
            app.UIFigure.Name = 'Epoch Player: Raw and Filtered';
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0.1;
            bottom = screenHeight*0.1;
            width = screenWidth*0.8;
            height = screenHeight*0.8;
            drawnow;
            app.UIFigure.Position = [left bottom width height];
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            %% Intialze and read in patient mat file variables 
            
            cla(app.waveformAxes,'reset')
            clear app.analysisTableSummary app.analysisTablePauseDetails app.analysisTableSpeechDetail app.energyMatrix app.filename app.audioName...
                app.indDetect app.waveformWithTime app.detectionWTime app.indSpeechStart app.indSpeechStop app.Fs app.EpochLabel app.audioFile app.waveform...
                app.matFilename app.audioWExt app.patientDx app.pExist app.epochGroups app.xlim1 app.xlim2 app.i app.h app.Text app.Ti app.labelString...
                app.pathname app.epochDone app.epochTotal app.epochMsg analysisTableSummary analysisTablePauseDetails analysisTableSpeechDetail energyMatrix filname...
                audioName indDetect waveformWithTime detectionWTime indSpeechStart indSpeechStop Fs EpochLabel audioFile waveform audioWExt;
            app.filename = [];
            app.audioName = [];
            app.indSpeechStart = [];
            app.indSpeechStop = [];
            app.audioWExt = [];
            app.audioFile = [];
            app.patientDx = []
            app.EpochLabel = [];
            app.waveform = [];
            app.Fs = [];
            app.waveformWithTime = [];
            app.detectionWTimeRaw = [];
            app.detectionWTimePFilt = [];
            app.detectionWTimeCFilt = [];
            app.detectionWTimeBFilt = [];
            app.detectionWTimeOFilt = [];
            
            app.indRawDetection = [];
            app.indPatientAudioOnly = [];
            app.indClinicianAudioOnly = [];
            app.indBuzzerAudioOnly = [];
            app.indOtherAudioOnly = [];
            
            pLabelCheck = 0;
            cLabelCheck = 0;
            bLabelCheck = 0;
            oLabelCheck = 0;
            
            app.Title = [];
            app.filtDectection = [];
            app.filtIndices = [];
            [filename, app.pathname, ~] = uigetfile('*.mat', 'Load Patient Mat file');
            app.matFilename = strcat(app.pathname,filename);
            
            load(app.matFilename)
            app.filename = filename;
            app.audioName = audioName;
            app.indSpeechStart = indSpeechStart;
            app.indSpeechStop = indSpeechStop;
            app.audioWExt = audioWExt;
            app.audioFile = strcat(app.pathname,app.audioWExt);
            app.patientDx = patientDx
            app.EpochLabel = EpochLabel;
            
            for p=1:length(app.EpochLabel)
                if app.EpochLabel{p} == 'P'
                    pLabelCheck = 1;
                elseif app.EpochLabel{p} == 'C'
                    cLabelCheck = 1;
                elseif app.EpochLabel{p} == 'B'
                    bLabelCheck = 1;
                elseif app.EpochLabel{p} == 'O'
                    oLabelCheck = 1;
                end
            end
            
            [app.waveform,app.Fs] = audioread(app.audioFile);
            
            
            if(length(app.waveform(1,:)) ~= 1) % Condition is true when file is a recording in stereo.
                app.waveform = sum(app.waveform, 2) / size(app.waveform,2); % Converts stereo recording to mono.
            end
            
            dt = 1/app.Fs;
            t = 0:dt:length(app.waveform)*dt - dt; %array of each sample in time domain
            app.waveformWithTime = zeros(2,(length(app.waveform)+2));
            app.waveformWithTime(1,(2:end - 1)) = app.waveform;
            app.waveformWithTime(2,(2:end - 1)) = t;
            app.waveformWithTime(2,end) = t(end);
            
            %% Raw Detection
            app.detectionWTimeRaw = zeros(1,length(app.waveformWithTime));
            
            for w=1:length(app.indSpeechStart)
                app.detectionWTimeRaw(app.indSpeechStart(w):app.indSpeechStop(w)) = 1;
            end
            app.indRawDetection = find(app.detectionWTimeRaw == 1);
            
            %% Patient Detection
            if pLabelCheck == 1
                q = 1;
                for x=1:length(app.EpochLabel)
                    if app.EpochLabel{x} == 'P'
                        indPatientOnlyLabel(q) = x;
                        q = q + 1;
                    end
                end
                j=1;
                for i=1:length(indPatientOnlyLabel)
                    indPatientSpeechStart(i) = app.indSpeechStart(indPatientOnlyLabel(j));
                    indPatientSpeechStop(i) = app.indSpeechStop(indPatientOnlyLabel(j));
                    j = j + 1;
                end
                app.detectionWTimePFilt = zeros(1,length(app.detectionWTimeRaw));
                for y=1:length(indPatientSpeechStart)
                    app.detectionWTimePFilt(indPatientSpeechStart(y):indPatientSpeechStop(y)) = 1;
                end
                app.indPatientAudioOnly = find(app.detectionWTimePFilt == 1);
            end
            %% Clinician Detection
            if cLabelCheck == 1
                q = 1;
                for x=1:length(app.EpochLabel)
                    if app.EpochLabel{x} == 'C'
                        indClinicianOnlyLabel(q) = x;
                        q = q + 1;
                    end
                end
                j=1;
                for i=1:length(indClinicianOnlyLabel)
                    indClinicianSpeechStart(i) = app.indSpeechStart(indClinicianOnlyLabel(j));
                    indClinicianSpeechStop(i) = app.indSpeechStop(indClinicianOnlyLabel(j));
                    j = j + 1;
                end
                
                app.detectionWTimeCFilt = zeros(1,length(app.detectionWTimeRaw));
                for y=1:length(indClinicianSpeechStart)
                    app.detectionWTimeCFilt(indClinicianSpeechStart(y):indClinicianSpeechStop(y)) = 1;
                end
                app.indClinicianAudioOnly = find(app.detectionWTimeCFilt == 1);
            end
            %% Buzzer Detection
            if bLabelCheck == 1
                q = 1;
                for x=1:length(app.EpochLabel)
                    if app.EpochLabel{x} == 'B'
                        indBuzzerOnlyLabel(q) = x;
                        q = q + 1;
                    end
                end
                j=1;
                for i=1:length(indBuzzerOnlyLabel)
                    indBuzzerSpeechStart(i) = app.indSpeechStart(indBuzzerOnlyLabel(j));
                    indBuzzerSpeechStop(i) = app.indSpeechStop(indBuzzerOnlyLabel(j));
                    j = j + 1;
                end
                j=1;
                app.detectionWTimeBFilt = zeros(1,length(app.detectionWTimeRaw));
                for y=1:length(indBuzzerSpeechStart)
                    app.detectionWTimeBFilt(indBuzzerSpeechStart(y):indBuzzerSpeechStop(y)) = 1;
                end
                app.indBuzzerAudioOnly = find(app.detectionWTimeBFilt == 1);
            end
            %% Other Detection
            if oLabelCheck == 1
                q = 1;
                for x=1:length(app.EpochLabel)
                    if app.EpochLabel{x} == 'O'
                        indOtherOnlyLabel(q) = x;
                        q = q + 1;
                    end
                end
                j=1;
                for i=1:length(indOtherOnlyLabel)
                    indOtherSpeechStart(i) = app.indSpeechStart(indOtherOnlyLabel(j));
                    indOtherSpeechStop(i) = app.indSpeechStop(indOtherOnlyLabel(j));
                    j = j + 1;
                end
                
                app.detectionWTimeOFilt = zeros(1,length(app.detectionWTimeRaw));
                for y=1:length(indOtherSpeechStart)
                    app.detectionWTimeOFilt(indOtherSpeechStart(y):indOtherSpeechStop(y)) = 1;
                end
                app.indOtherAudioOnly = find(app.detectionWTimeOFilt == 1);
            end
        end

        % Button pushed function: PlayRawButton
        function PlayRawButtonPushed(app, event)
            % Plays raw detection output from speech analysis algorithm
            
            cla(app.waveformAxes,'reset')
            
            plot(app.waveformAxes, app.waveformWithTime(2,:), app.waveformWithTime(1,:))
            hold(app.waveformAxes,'on')
            plot(app.waveformAxes, app.waveformWithTime(2,:),app.detectionWTimeRaw, 'color', [0.4 0.2 0.6])
            app.waveformAxes.YLim = [-1.2,1.2];
            app.waveformAxes.XLim = [0, 195];
            title = ['RAW Detection: ',num2str(app.audioName), ',   INTdx: ', num2str(app.patientDx(73)),',   Gender: ', num2str(app.patientDx(80)), ',   Age:', num2str(app.patientDx(29))];
            app.waveformAxes.Title.String = title;
            app.waveformAxes.XLabel.String = 'Time (s)';
            app.waveformAxes.YLabel.String = 'Waveform Magnitude';
            clear sound
            sound(app.waveformWithTime(1,app.indRawDetection), app.Fs)
        end

        % Button pushed function: PlayFilteredButton
        function PlayFilteredButtonPushed(app, event)
            % Dependent on the drop down selection 
            % will play and display filtered detection
            
            cla(app.waveformAxes,'reset')
            plot(app.waveformAxes, app.waveformWithTime(2,:), app.waveformWithTime(1,:))
            hold(app.waveformAxes,'on')
            plot(app.waveformAxes, app.waveformWithTime(2,:),app.filtDectection, 'color', [0.4 0.2 0.6])
            app.waveformAxes.YLim = [-1.2,1.2];
            app.waveformAxes.XLim = [0, 195];
            app.waveformAxes.Title.String = app.Title;
            app.waveformAxes.XLabel.String = 'Time (s)';
            app.waveformAxes.YLabel.String = 'Waveform Magnitude';
            clear sound
            sound(app.waveformWithTime(1,app.filtIndices), app.Fs)
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            clear sound
        end

        % Value changed function: SelectFilterDropDown
        function SelectFilterDropDownValueChanged(app, event)
            % selection assigns properties for play buttons and 
            % updates plot title
            
            clear sound
            value = app.SelectFilterDropDown.Value;
            app.Title = [];
            app.filtDectection = [];
            app.filtIndices = [];
            
            switch value
                case 'Patient'
                    app.Title = ['PATIENT ONLY Filtered Detection   PID: ',num2str(app.audioName), ',   INTdx: ', num2str(app.patientDx(73)),',   Gender: ', num2str(app.patientDx(80)), ',   Age:', num2str(app.patientDx(29))];
                    app.filtDectection = app.detectionWTimePFilt;
                    app.filtIndices = app.indPatientAudioOnly;
                case 'Clinician'
                    app.Title = ['CLINICIAN ONLY Filtered Detection   PID: ',num2str(app.audioName), ',   INTdx: ', num2str(app.patientDx(73)),',   Gender: ', num2str(app.patientDx(80)), ',   Age:', num2str(app.patientDx(29))];
                    app.filtDectection = app.detectionWTimeCFilt;
                    app.filtIndices = app.indClinicianAudioOnly;
                case 'Buzzer'
                    app.Title = ['BUZZER ONLY Filtered Detection   PID: ',num2str(app.audioName), ',   INTdx: ', num2str(app.patientDx(73)),',   Gender: ', num2str(app.patientDx(80)), ',   Age:', num2str(app.patientDx(29))];
                    app.filtDectection = app.detectionWTimeBFilt;
                    app.filtIndices = app.indBuzzerAudioOnly;
                case 'Other'
                    app.Title = ['OTHER ONLY Filtered Detection   PID: ',num2str(app.audioName), ',   INTdx: ', num2str(app.patientDx(73)),',   Gender: ', num2str(app.patientDx(80)), ',   Age:', num2str(app.patientDx(29))];
                    app.filtDectection = app.detectionWTimeOFilt;
                    app.filtIndices = app.indOtherAudioOnly;
            end


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PlayRawButton = uibutton(app.UIFigure, 'push');
            app.PlayRawButton.ButtonPushedFcn = createCallbackFcn(app, @PlayRawButtonPushed, true);
            app.PlayRawButton.Text = 'Play Raw ';
            app.PlayFilteredButton = uibutton(app.UIFigure, 'push');
            app.PlayFilteredButton.ButtonPushedFcn = createCallbackFcn(app, @PlayFilteredButtonPushed, true);
            app.PlayFilteredButton.Text = 'Play Filtered';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.waveformAxes = uiaxes(app.UIFigure);
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SelectFilterDropDownLabel = uilabel(app.UIFigure);
            app.SelectFilterDropDownLabel.Text = 'Select Filter';
            app.SelectFilterDropDown = uidropdown(app.UIFigure);
            app.SelectFilterDropDown.Items = {'', 'Patient', 'Clinician', 'Buzzer', 'Other'};
            app.SelectFilterDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectFilterDropDownValueChanged, true);
            app.SelectFilterDropDown.Value = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_play_epoch_label_filters

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end