classdef GUI_speech_epoch_play_and_label < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        playButton                  matlab.ui.control.Button
        previousButton              matlab.ui.control.Button
        EpochLabelDropDownLabel     matlab.ui.control.Label
        EpochLabelDropDown          matlab.ui.control.DropDown
        loadpatientfileButton       matlab.ui.control.Button
        nextButton                  matlab.ui.control.Button
        saveButton                  matlab.ui.control.Button
        stopButton                  matlab.ui.control.Button
        SelectedEpochTextAreaLabel  matlab.ui.control.Label
        SelectedEpochTextArea       matlab.ui.control.TextArea
        waveformAxes                matlab.ui.control.UIAxes
        MediateDescriptionTextAreaLabel  matlab.ui.control.Label
        MediateDescriptionTextArea  matlab.ui.control.TextArea
        PlayFinalEditButton         matlab.ui.control.Button
    end

    %% GUI_speech_epoch_play_and_label.mlapp is a GUI that reads in a patient
    % audio file with detected speech epochs and allows the user to play and 
    % label speech epochs one of four choices: 'Patient', 'Clinician', 
    % 'Buzzer' and 'Other'. Labeled speech epochs are assigned to variable 
    % EpochLabel and appended to respective patient mat file. The directory
    % in which you load patient mat files from must have respective patient 
    % speech task audio files.
   
    %% THREE EDITING MODES:
    % First Runthrough: Play and label epochs for the first time.
    % After selecting a label from the dropdown, GUI will automatically
    % play next epoch. Allows mid session save and close. Loads mid 
    % session patient mat file and positions/plays speech epoch last 
    % labeled
    %
    % Validation: Play previously labeled epochs. Selecting the play 
    % button now acts similar to the First Runthrough label dropdown.
    % After selecting play button, current speech epoch will play 
    % and automatically move to the next epoch. Allows mid session
    % save and close. Loads all patient mat files at first epoch 
    % label regardless of mid session work.
    %
    % Mediation: Requires mat file MismatchEpochLabelIndices_7_11.mat.
    % Loads epoch labeled patient mat file and determines which speech
    % epochs have label discrepancies based off of variable 
    % LabelMismatchIndices. Plays only mismatched epoch labels and 
    % requires label mediation as well as a sentence description on 
    % mediation decision
            
    properties (Access = private)
        % Create properties out of all patient mat file variables 
        % so they may be used across functions
        analysisTableSummary
        analysisTablePauseDetails
        analysisTableSpeechDetails
        analysisTableSummaryPatient 
        analysisTablePauseDetailsPatient
        analysisTableSpeechDetailsPatient
        energyMatrix
        energyMatrixPatientOnly
        filename
        audioName;
        indDetect;
        waveformWithTime;
        detectionWTime
        indSpeechStart;
        indSpeechStop;
        Fs;
        player;
        validate;
        mediate;
        theIndices;
        Mi;
        mediateDescription;
        
        EpochLabel;
        audioFile;
        waveform;
        matFilename;
        audioWExt;
        patientDx;
        pExist;
        saveCheck;
        epochMsg;
        stopVal;
        pathname;
        epochDone;
        LabelMismatchIndices;
        epochTotal;
        
        epochGroups;
        xlim1;
        xlim2;
        i;
        h;
        Text;
        Ti;
        labelString;
        
    end
    
    properties (Access = public)
        % Description
    end
    
    methods (Access = private)
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Initializing GUI specific settings on start up
            % 
            
            app.UIFigure.Name = 'Speech Epoch Audio Player and Labeler';
            % Create window size relative to user resolution
            % If bugs exist on specific resolution sizes, manual resizing 
            % of GUI window in 'Design View' may be needed
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0.1;
            bottom = screenHeight*0.1;
            width = screenWidth*0.8;
            height = screenHeight*0.8;
            drawnow;
            app.UIFigure.Position = [left bottom width height];
            % Initialize all buttons except 'load' as unpressable
            app.previousButton.Enable = 'off';
            app.nextButton.Enable = 'off';
            app.playButton.Enable = 'off';
            app.saveButton.Enable = 'off';
            app.stopButton.Enable = 'off';
           
            app.saveCheck = 0;
            app.SelectedEpochTextArea.Editable = 'off';
            app.MediateDescriptionTextArea.Visible = 'off';
            app.MediateDescriptionTextAreaLabel.Visible = 'off';
            app.PlayFinalEditButton.Visible = 'off';
            %app.PlayFinalEditButton.Visible = 'off';
            fig1 = uifigure;
            clear mode
            
            %% Pop up window to choose editing mode (mode descriptions above)
            message1 = sprintf('Choose Editing Mode');
            mode = uiconfirm(fig1,message1, 'Editing Mode',...
                'Options', {'First Runthrough', 'Validation', 'Mediation', 'Cancel'}, 'DefaultOption',1, 'CancelOption', 4);
            mode = convertCharsToStrings(mode)
            % Assign editing mode properties accordingly
            if mode == "Validation"
                app.validate = 1;
                app.mediate=0;
            elseif mode=="Mediation"
                app.mediate = 1;
                app.validate = 0;
                app.MediateDescriptionTextArea.Visible = 'on';
                app.MediateDescriptionTextAreaLabel.Visible = 'on';
            else
                app.validate = 0;
                app.mediate = 0;
            end
            delete(fig1);
            
            
        end

        % Button pushed function: playButton
        function playButtonPushed(app, event)
            %% Play current selected speech epoch, allow for 
            % mid play stop interruption, animate play needle
            % to slide across waveform according to sound playing
            
            % Condition: current selected epoch must be within the 
            % bounds of patient speech epoch amount 
            if (app.i <= length(app.indSpeechStart))
                
                % Mediate condition: If final mismatched epoch playing
                % allow for PlayFinalEditButton to be pressable
                if app.mediate == 1
                    if app.i == app.mediateDescription{end-1,1}
                        app.PlayFinalEditButton.Visible = 'on';
                    end
                end
                
                % Condition: if on first epoch, don't allow previous to be p
                % pushed
                if(app.i == 1)
                    app.previousButton.Enable = 'off'
                else
                    app.previousButton.Enable = 'on'
                end
                clear app.epochDone
                % Display and update current epoch number user is editing
                % out of how many epochs exist 
                app.epochDone = num2str(app.i);
                app.epochTotal = num2str(length(app.indSpeechStart));
                app.epochMsg = [app.epochDone ' of ' app.epochTotal];
                app.SelectedEpochTextArea.Value = app.epochMsg;
                
                % Update current epoch's label (or lack there of)
                app.EpochLabelDropDown.Value = app.labelString{app.i};
                
                time = 0;
                % If another epoch is still playing, make sure to stop 
                % it before starting th next
                clear sound
                
                %% Play current epoch label and animate play needle accordingly
                sound(app.waveformWithTime(1,app.indSpeechStart(app.i):app.indSpeechStop(app.i)), app.Fs)
                tic % Starts Matlab timer
                t=toc;
                while 1
                    % Condition: allow for mid animation stop interruption 
                    if ((time > app.epochGroups(app.i,2)) || (app.stopVal == 1))
                        app.stopVal = 0;
                        break
                    end
                    time = app.epochGroups(app.i,1) + t;
                    % Update play needle plot positiion
                    set(app.h,'xdata',(time)*[1,1])
                    drawnow
                    t= toc; % get the current time for the next update
                    % Update plot limits if valid
                    if(app.h.XData(1) > (app.xlim1 + 18))
                        app.xlim1 = app.xlim1 + 15;
                        app.xlim2 = app.xlim2 + 15;
                        xlim(app.waveformAxes, [app.xlim1 app.xlim2]);
                    elseif(app.h.XData(1) < (app.xlim1 + 2) )
                        app.xlim1 = app.xlim1 - 10;
                        app.xlim2 = app.xlim2 - 10;
                        xlim(app.waveformAxes, [app.xlim1 app.xlim2])
                    end

                end
                % Validation condition: automatically move to next 
                % epoch position if in validation mode
                if(app.validate == 1 && app.i < length(app.indSpeechStart))
                    app.nextButtonPushed
                end
                
            end
            
        end

        % Button pushed function: previousButton
        function previousButtonPushed(app, event)
            %% Move play needle to previous epoch of current
            % epoch. Update plot limits if applicable 
            
            % Condition: if epoch is first epoch of file 
            % don't allow for it to be pressed again
            if(app.i - 1 == 1)
                app.previousButton.Enable = 'off'
            else
                app.previousButton.Enable = 'on'
            end
            
            % Mediation condition: move to previous mismatched 
            % epoch rather than neighboring epoch (unless is 
            % previous mismatched)
            if app.mediate == 1
                app.Mi = app.Mi - 1
                app.i = app.theIndices(app.Mi)
                app.MediateDescriptionTextArea.Value = char(app.mediateDescription{app.Mi,2});            

            else
                app.i = app.i - 1;
            end
            
            % Update epoch label (or lack there of) 
            app.EpochLabelDropDown.Value = app.labelString{app.i};
            
            % Update play needle plot position
            set(app.h,'xdata',(app.epochGroups(app.i,1))*[1,1]);
            if(app.h.XData(1) > (app.xlim1 + 18))
                app.xlim1 = app.xlim1 + 15;
                app.xlim2 = app.xlim2 + 15;
                xlim(app.waveformAxes, [app.xlim1 app.xlim2]);
            elseif(app.h.XData(1) < (app.xlim1 + 2) )
                app.xlim1 = app.xlim1 - 10;
                app.xlim2 = app.xlim2 - 10;
                xlim(app.waveformAxes, [app.xlim1 app.xlim2])
            end
            
        end

        % Button pushed function: loadpatientfileButton
        function loadpatientfileButtonPushed(app, event)
            %% Load patient mat file and perform all property
            % initialization 
            
            % Reset plot if loading file after finishing another
            cla(app.waveformAxes,'reset')
            clear app.analysisTableSummary app.analysisTablePauseDetails app.analysisTableSpeechDetail app.energyMatrix app.filename app.audioName...
                app.indDetect app.waveformWithTime app.detectionWTime app.indSpeechStart app.indSpeechStop app.Fs app.EpochLabel app.audioFile app.waveform...
                app.matFilename app.audioWExt app.patientDx app.pExist app.epochGroups app.xlim1 app.xlim2 app.i app.h app.Text app.Ti app.labelString...
                app.pathname app.epochDone app.epochTotal app.epochMsg analysisTableSummary analysisTablePauseDetails analysisTableSpeechDetail energyMatrix filname...
                audioName indDetect waveformWithTime detectionWTime indSpeechStart indSpeechStop Fs EpochLabel audioFile waveform audioWExt;
            
            % load patient mat file     
            [filename, app.pathname, ~] = uigetfile('*.mat', 'Load Patient Mat file');
            app.matFilename = strcat(app.pathname,filename);
            app.theIndices = [];
            
            load(app.matFilename)
            app.PlayFinalEditButton.Visible = 'off';
            
            % Mediation condition: If in mediation mode load and assign
            % LabelMismatchIndices
            if app.mediate == 1
                load('D:\Documents\Research2017\MATLAB\FeatureAnalysis\MismatchEpochLabelIndices_7_11.mat')
                app.LabelMismatchIndices = LabelMismatchIndices;
            end
            
            % Initialize property values from patient mat file
            app.SelectedEpochTextArea.Value = '';
            %app.playButton.Enable = 'on'
            app.analysisTableSummary = analysisTableSummary;
            app.analysisTablePauseDetails = analysisTablePauseDetails;
            app.analysisTableSpeechDetails = analysisTableSpeechDetails;
            app.energyMatrix = energyMatrix;
            app.filename = filename;
            app.audioName = audioName;
            app.indSpeechStart = indSpeechStart;
            app.indSpeechStop = indSpeechStop;
            app.audioWExt = audioWExt;
            %app.energyMatrixPatientOnly = energyMatrixPatientOnly;
            %app.analysisTableSummaryPatient = analysisTableSummaryPatient;
            %app.analysisTablePauseDetailsPatient = analysisTablePauseDetailsPatient;
            %app.analysisTableSpeechDetailsPatient = analysisTableSpeechDetailsPatient;
            
            
            %app.audioWExt = [app.audioName '.m4a']
            app.audioFile = strcat(app.pathname,app.audioWExt);
            
            % Values needed for mediation editing mode
            [rowSizeEpLabel,colSizeEpLabel] = size(app.LabelMismatchIndices)
            app.Mi = 1;
            app.MediateDescriptionTextArea.Value = '';
            app.MediateDescriptionTextAreaLabel.Text = 'Mediate Description';
            app.mediateDescription = '';

            % Initialize mediateDescription which houses all mediation choice
            % descriptions for respective patient mat file
            for i=1:rowSizeEpLabel
                if str2num(app.audioName) == app.LabelMismatchIndices{i,1}
                    t=1;
                    for j=2:colSizeEpLabel
                        if isempty(app.LabelMismatchIndices{i,j}) == 0
                            app.theIndices(t) = app.LabelMismatchIndices{i,j}
                            app.mediateDescription{t,1} = app.theIndices(t);
                            app.mediateDescription{t,2} = '';
                            t=t+1;
                        end
                    end
                    app.mediateDescription{t,1} = "Summary Description:"
                    app.mediateDescription{t,2} = '';
                end
            end
            
            % Condition: determine if patientDx exists for given patient mat 
            % file. If it does assign it to respective property
            if(exist('patientDx','var') == 1)
                app.pExist = 1;
                app.patientDx = patientDx;
            else
                app.pExist = 0;
            end
            
            % Create audio waveform of entire speech task
            [app.waveform,app.Fs] = audioread(app.audioFile);
            
            
            if(length(app.waveform(1,:)) ~= 1) % Condition is true when file is a recording in stereo.
                app.waveform = sum(app.waveform, 2) / size(app.waveform,2); % Converts stereo recording to mono.
            end
            
            % Intialize audio waveform and detection specific
            % variables
            dt = 1/app.Fs;
            t = 0:dt:length(app.waveform)*dt - dt; %array of each sample in time domain
            app.waveformWithTime = zeros(2,(length(app.waveform)+2));
            app.waveformWithTime(1,(2:end - 1)) = app.waveform;
            app.waveformWithTime(2,(2:end - 1)) = t;
            app.waveformWithTime(2,end) = t(end);
            app.detectionWTime = zeros(1,length(app.waveformWithTime));
            for w=1:length(app.indSpeechStart)
                app.detectionWTime(app.indSpeechStart(w):app.indSpeechStop(w)) = 1;
            end
            
            % Create plot of waveform with detection 
            app.i = 1;
            plot(app.waveformAxes, app.waveformWithTime(2,:), app.waveformWithTime(1,:))
            hold(app.waveformAxes,'on')
            plot(app.waveformAxes, app.waveformWithTime(2,:),app.detectionWTime, 'color', [0.4 0.2 0.6])
            app.waveformAxes.YLim = [-1.2,1.2];
            app.xlim1 = 0;
            app.xlim2 = 20;
            app.waveformAxes.XLim = [app.xlim1 app.xlim2];
            app.h=line(app.waveformAxes, [0,0], [-60,5],'color','k','marker', 'o', 'linewidth', 2);
            app.waveformAxes.Title.String = num2str(app.audioName);
            app.waveformAxes.XLabel.String = 'Time (s)';
            app.waveformAxes.YLabel.String = 'Waveform Magnitude';
            app.Text = NaN(length(app.indSpeechStart),4);
            app.Ti = 1;
            % Allow button functionality 
            app.saveButton.Enable = 'on';
            app.playButton.Enable = 'on';
            app.nextButton.Enable = 'on';
            app.stopButton.Enable = 'on';
            app.labelString = cell(length(app.indSpeechStart),1);
            %app.labelString{:,1} = '';
            app.stopVal = 0;
            app.EpochLabel = cell(1,length(app.indSpeechStart));
            
            % Initilaize labelString
            for x = 1:length(app.indSpeechStart)
                app.labelString{x,1} = '';
            end
            %legend(app.waveformAxes, 'Waveform', 'Detection')
            
            for z = 1:length(app.indSpeechStart)
                epoch = app.waveformWithTime(2,app.indSpeechStart(z):app.indSpeechStop(z));
                app.epochGroups(z,1) = epoch(1);
                app.epochGroups(z,2) = epoch(end);
            end

            % Condition: If preexisting epoch labels are present 
            % load and display them all in the plot
            if(exist('EpochLabel', 'var') == 1)
                app.EpochLabel = EpochLabel;
                
                
                for x = 1:length(app.EpochLabel)
                    value = app.EpochLabel{x};
                    
                    %{
                    if(isempty(value) == 1)
                        app.nextButton.Enable = 'off';
                    else
                        app.nextButton.Enable = 'on';
                    end
                    %}
                    
                    clear mark
                    
                    if(isnan(app.Text(app.i,app.Ti)) == 0)
                        delete(app.Text(app.i,app.Ti));
                        app.Text(app.i,:) = NaN(1,4);
                    end
                    
                    switch value
                        case 'P'
                            app.labelString{x} = 'Patient';
                            %app.EpochLabel{x} = 'P';
                            mark = 'P';
                            app.Ti = 1;
                            %if(exist('app.epochGroups(x,1)', 'var') == 1)
                                xpos = app.epochGroups(x,1);
                                app.Text(x,1) = text(app.waveformAxes,xpos, 1.05, mark, 'FontSize', 14, 'FontWeight', 'bold');
                            %end
                        case 'C'
                            app.labelString{x} = 'Clinician';
                            %app.EpochLabel{x} = 'C';
                            mark = 'C';
                            app.Ti = 2;
                            %if(exist('app.epochGroups(x,1)', 'var') == 1)
                                xpos = app.epochGroups(x,1);
                                app.Text(x,2) = text(app.waveformAxes,xpos, 1.05, mark, 'FontSize', 14, 'FontWeight', 'bold');
                            %end
                        case 'B'
                            app.labelString{x} = 'Buzzer';
                            %app.EpochLabel{x} = 'B';
                            mark = 'B';
                            app.Ti = 3;
                            %if(exist('app.epochGroups(x,1)', 'var') == 1)
                                xpos = app.epochGroups(x,1);
                                app.Text(x,3) = text(app.waveformAxes,xpos, 1.05, mark, 'FontSize', 14, 'FontWeight', 'bold');
                            %end
                        case 'O'
                            app.labelString{x} = 'Other';
                           %app.EpochLabel{x} = 'O';
                            mark = 'O';
                            app.Ti = 4;
                            %if(exist('app.epochGroups(x,1)', 'var') == 1)
                                xpos = app.epochGroups(x,1);
                                app.Text(x,4) = text(app.waveformAxes,xpos, 1.05, mark, 'FontSize', 14, 'FontWeight', 'bold');
                            %end
                    end
                end
                % Vaidation condition: start play needle on first epoch
                if(app.validate == 1)
                    app.i = 1;
                % Mediation condition: start play needle on first mismatched epoch
                elseif app.mediate == 1
                    app.i = app.theIndices(app.Mi);
                else
                    app.i = x;
                end
            end
            
            
        end

        % Value changed function: EpochLabelDropDown
        function EpochLabelDropDownValueChanged(app, event)
            %% Assign values and update plot after selecting 
            % epoch label from dropdown
            
            % Condition: can only change values if within the bounds of the epochs
            % present 
            if(app.i ~= (length(app.indSpeechStart) + 1) || app.validate == 1)
                
                value = app.EpochLabelDropDown.Value;
                
                
                clear mark
                
                % Initilaize Text property to display selected epoch label
                % on plot
                if(isnan(app.Text(app.i,app.Ti)) == 0)
                    delete(app.Text(app.i,app.Ti));
                    app.Text(app.i,:) = NaN(1,4);
                end
                
                % Assign variables depedent on selected epoch label
                % Refer to case 'Patient' comments for questions on 
                % all other cases provided
                switch value
                    case 'Patient'
                        app.labelString{app.i} = 'Patient';
                        if app.validate == 1
                            clear app.EpochLabel{app.i}
                        end
                        % EpochLabel is what is ultimately appended to patient mat file
                        app.EpochLabel{1,app.i} = 'P';
                        
                        % Creating plot label of selected epoch label
                        mark = 'P';
                        app.Ti = 1;
                        xpos = app.epochGroups(app.i,1);
                        app.Text(app.i,1) = text(app.waveformAxes,xpos, 1.05, mark, 'FontSize', 14, 'FontWeight', 'bold');
                        
                        % First Runthrough and Mediation condition: automatically play  
                        % next epoch after labeling current epoch
                        if app.validate == 0
                            app.nextButtonPushed
                            pause(.5)
                            app.playButtonPushed
                        end
                    case 'Clinician'
                        app.labelString{app.i} = 'Clinician';
                        if app.validate == 1
                            clear app.EpochLabel{app.i}
                        end
                        app.EpochLabel{1,app.i} = 'C';
                        mark = 'C';
                        app.Ti = 2;
                        xpos = app.epochGroups(app.i,1);
                        app.Text(app.i,2) = text(app.waveformAxes,xpos, 1.05, mark, 'FontSize', 14, 'FontWeight', 'bold');
                        if app.validate == 0
                            app.nextButtonPushed
                            pause(.5)
                            app.playButtonPushed
                        end
                    case 'Buzzer'
                        app.labelString{app.i} = 'Buzzer';
                        if app.validate == 1
                            clear app.EpochLabel{app.i}
                        end
                        app.EpochLabel{1,app.i} = 'B';
                        mark = 'B';
                        app.Ti = 3;
                        xpos = app.epochGroups(app.i,1);
                        app.Text(app.i,3) = text(app.waveformAxes,xpos, 1.05, mark, 'FontSize', 14, 'FontWeight', 'bold');
                        if app.validate == 0
                            app.nextButtonPushed
                            pause(.5)
                            app.playButtonPushed
                        end
                    case 'Other'
                        app.labelString{app.i} = 'Other';
                        if app.validate == 1
                            clear app.EpochLabel{app.i}
                        end
                        app.EpochLabel{1,app.i} = 'O';
                        mark = 'O';
                        app.Ti = 4;
                        xpos = app.epochGroups(app.i,1);
                        app.Text(app.i,4) = text(app.waveformAxes,xpos, 1.05, mark, 'FontSize', 14, 'FontWeight', 'bold');
                        if app.validate == 0
                            app.nextButtonPushed
                            pause(.5)
                            app.playButtonPushed
                        end
                end
            end


        end

        % Button pushed function: nextButton
        function nextButtonPushed(app, event)
            %% Update play needle and plot limits (if applicable)
            % position to next epoch 
            
            % Condition: disable previous button on first epoch 
            if((app.i + 1) == 1)
                app.previousButton.Enable = 'off'
            else
                app.previousButton.Enable = 'on'
            end
            
            % Assign mediateDsescription value
            if(app.i + 1<= length(app.indSpeechStart))
                app.mediateDescription{app.Mi,2} = string([char(app.labelString{app.i}) ,': ',char(app.MediateDescriptionTextArea.Value)]);
                
                % Mediation condition: assign appropriate 
                % values needed to jump to next mismatch epoch
                if app.mediate == 1
                    app.Mi = app.Mi + 1;
                    app.i = app.theIndices(app.Mi);
                    app.MediateDescriptionTextArea.Value = app.mediateDescription{app.Mi,2};
                else
                    app.i = app.i + 1;
                end
                app.EpochLabelDropDown.Value = app.labelString{app.i};
                
                % Update plot limits if applicable
                set(app.h,'xdata',(app.epochGroups(app.i,1))*[1,1]);
                if(app.h.XData(1) > (app.xlim1 + 18))
                    app.xlim1 = app.xlim1 + 15;
                    app.xlim2 = app.xlim2 + 15;
                    xlim(app.waveformAxes, [app.xlim1 app.xlim2]);
                elseif(app.h.XData(1) < (app.xlim1 + 2) )
                    app.xlim1 = app.xlim1 - 2;
                    app.xlim2 = app.xlim2 - 2;
                    xlim(app.waveformAxes, [app.xlim1 app.xlim2])
                end
            end
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            %% Append all data created from epoch labeling session
            % to respective patient mat file
            
            app.stopButtonPushed;
            [lengthMediateDes,~] = size(app.mediateDescription)
            if app.mediate == 1
                app.mediateDescription{lengthMediateDes,2} = string(app.MediateDescriptionTextArea.Value);
            end
            analysisTableSummary = app.analysisTableSummary;
            analysisTablePauseDetails = app.analysisTablePauseDetails;
            analysisTableSpeechDetails = app.analysisTableSpeechDetails;
            analysisTableSummaryPatient = app.analysisTableSummaryPatient;
            analysisTablePauseDetailsPatient = app.analysisTablePauseDetailsPatient;
            analysisTableSpeechDetailsPatient = app.analysisTableSpeechDetailsPatient;
            energyMatrix = app.energyMatrix;
            energyMatrixPatientOnly = app.energyMatrixPatientOnly;
            [~, filename] = fileparts(app.matFilename);
            audioName = app.audioName;
            indDetect = app.indDetect;
            waveformWithTime = app.waveformWithTime;
            detectionWTime = app.detectionWTime;
            indSpeechStart = app.indSpeechStart;
            indSpeechStop = app.indSpeechStop;
            Fs = app.Fs;
            audioWExt = app.audioWExt;
            mediateDescription = app.mediateDescription;
            if(app.pExist == 1)
                patientDx = app.patientDx;
            end
            EpochLabel = app.EpochLabel;
            if(filename(1:17) == 'MEDIATED_LABELED_')
                newFileName = filename;
            else
                newFileName = ['MEDIATED_',filename];
            end
            app.UIFigure.Interruptible = 'off'
            if(app.pExist == 1)
                cd(app.pathname )
                uisave({'analysisTableSummary', 'analysisTablePauseDetails', 'analysisTableSpeechDetails',...
                    'energyMatrix', 'filename', 'audioName',  'indSpeechStart', 'indSpeechStop', 'Fs', 'audioWExt',...
                    'patientDx', 'EpochLabel','mediateDescription','analysisTableSummaryPatient','analysisTablePauseDetailsPatient',...
                    'analysisTableSpeechDetailsPatient','analysisTablePauseDetailsPatient', 'energyMatrixPatientOnly'},newFileName)
                app.saveCheck = 1;
            else
                cd(app.pathname)
                uisave({'analysisTableSummary', 'analysisTablePauseDetails', 'analysisTableSpeechDetails',...
                    'energyMatrix', 'filename', 'audioName',  'indSpeechStart', 'indSpeechStop', 'Fs','audioWExt',...
                    'EpochLabel', 'mediateDescription''analysisTableSummaryPatient','analysisTablePauseDetailsPatient',...
                    'analysisTableSpeechDetailsPatient','analysisTablePauseDetailsPatient', 'energyMatrixPatientOnly'},newFileName)
                app.saveCheck = 1;
            end
            app.UIFigure.Interruptible = 'on'
            %directory = uigetdir(currentFolder,'Pick a folder to save ');
            %fullDirectory = [directory,'\', app.audioName];
            %save(fullDirectory, 'epochLabels', '-append')
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            % Just stops sound and assign property that 
            % interrupts play needle animation
            clear sound
            app.stopVal = 1;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           %% Provides a check that requires you to save
           % any unsaved work before closing out the GUI
            
            app.stopButtonPushed;
            if((app.saveCheck == 0) && (isempty(app.EpochLabel) == 0))
                fig = uifigure;
                clear selection
                message = sprintf('You have unsaved work, \n Save Now?');
                selection = uiconfirm(fig,message, 'Unsaved Work',...
                    'Options', {'Save', 'Cancel'}, 'DefaultOption',1, 'CancelOption', 2);
                selection = convertCharsToStrings(selection);
                if(selection == "Save")
                    app.saveButtonPushed;
                    delete(fig)
                else
                    delete(fig)
                    return;
                end
                delete(fig)
            end
            delete(app)
        end

        % Button pushed function: PlayFinalEditButton
        function PlayFinalEditButtonPushed(app, event)
            % Button only appears if mediation mode is on 
            % and final mismatched epoch label is mediated.
            % 
            % Plays audio of all patient labeled epochs ONLY and 
            % provides a text area to give your summary thoughts
            % on what the user is hearing
            
            q = 1;
            for x=1:length(app.EpochLabel)
                if app.EpochLabel{x} == 'P'
                    indPatientOnlyLabel(q) = x;
                    q = q + 1;
                end
            end
            j=1;
            for i=1:length(indPatientOnlyLabel)
                indPatientSpeechStart(i) = app.indSpeechStart(indPatientOnlyLabel(j));
                indPatientSpeechStop(i) = app.indSpeechStop(indPatientOnlyLabel(j));
                j = j + 1;
            end
            %fuck = [indPatientSpeechStart(:):indPatientSpeechStop(:)]
            patientOnlyDetection = zeros(1,length(app.detectionWTime));
            for y=1:length(indPatientSpeechStart)
                patientOnlyDetection(indPatientSpeechStart(y):indPatientSpeechStop(y)) = 1;
            end
            indPatientAudioOnly = find(patientOnlyDetection == 1);
            sound(app.waveformWithTime(1,indPatientAudioOnly),app.Fs)
            
            app.MediateDescriptionTextArea.Value = '';
            app.MediateDescriptionTextAreaLabel.Text = 'Summary Description:';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.playButton = uibutton(app.UIFigure, 'push');
            app.playButton.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.playButton.Text = 'play';
            app.previousButton = uibutton(app.UIFigure, 'push');
            app.previousButton.ButtonPushedFcn = createCallbackFcn(app, @previousButtonPushed, true);
            app.previousButton.Text = 'previous';
            app.EpochLabelDropDownLabel = uilabel(app.UIFigure);
            app.EpochLabelDropDownLabel.Text = 'Epoch Label';
            app.EpochLabelDropDown = uidropdown(app.UIFigure);
            app.EpochLabelDropDown.Items = {'', 'Patient', 'Clinician', 'Buzzer', 'Other'};
            app.EpochLabelDropDown.ValueChangedFcn = createCallbackFcn(app, @EpochLabelDropDownValueChanged, true);
            app.EpochLabelDropDown.Value = '';
            app.loadpatientfileButton = uibutton(app.UIFigure, 'push');
            app.loadpatientfileButton.ButtonPushedFcn = createCallbackFcn(app, @loadpatientfileButtonPushed, true);
            app.loadpatientfileButton.Text = 'load patient file';
            app.nextButton = uibutton(app.UIFigure, 'push');
            app.nextButton.ButtonPushedFcn = createCallbackFcn(app, @nextButtonPushed, true);
            app.nextButton.Text = 'next';
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.stopButton = uibutton(app.UIFigure, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = 'stop';
            app.SelectedEpochTextAreaLabel = uilabel(app.UIFigure);
            app.SelectedEpochTextAreaLabel.Text = 'Selected Epoch';
            app.SelectedEpochTextArea = uitextarea(app.UIFigure);
            app.SelectedEpochTextArea.Editable = 'off';
            app.waveformAxes = uiaxes(app.UIFigure);
            app.MediateDescriptionTextAreaLabel = uilabel(app.UIFigure);
            app.MediateDescriptionTextAreaLabel.Text = 'Mediate Description';
            app.MediateDescriptionTextArea = uitextarea(app.UIFigure);
            app.PlayFinalEditButton = uibutton(app.UIFigure, 'push');
            app.PlayFinalEditButton.ButtonPushedFcn = createCallbackFcn(app, @PlayFinalEditButtonPushed, true);
            app.PlayFinalEditButton.Text = 'Play Final Edit';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_speech_epoch_play_and_label

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end